classdef creatView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Caocao    matlab.ui.control.Button
        Button_1  matlab.ui.control.Button
        Button    matlab.ui.control.Button
        Button_2  matlab.ui.control.Button
        Button_3  matlab.ui.control.Button
        Button_4  matlab.ui.control.Button
        Button_5  matlab.ui.control.Button
        Button_6  matlab.ui.control.Button
        Button_7  matlab.ui.control.Button
        Button_8  matlab.ui.control.Button
    end

    
    properties (Access = public)
        diyView
        i
        move
        a
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Window button down function: UIFigure
        function emptyClick(app, event)
            app.a = [app.Button_1 app.Button app.Button_2 app.Button_3 app.Button_4 app.Button_5 app.Button_6 app.Button_7 app.Button_8];
            position = app.diyView.ButtonHandle;
            app.a(app.i).Icon = app.diyView.IconHandle;
            clickPoint = app.UIFigure.CurrentPoint;
            if(app.move==1)
             if(clickPoint(1)>5&&clickPoint(1)<105)
                if(clickPoint(2)>5&&clickPoint(2)<105)
                    position = [5,5,position(3),position(4)];
                    app.a(app.i).Position = position;
                    app.i =app.i + 1;
                    app.move=app.move-1;
                elseif(clickPoint(2)>105&&clickPoint(2)<205)
                    position = [5,105,position(3),position(4)];
                    app.a(app.i).Position = position;
                    app.i =app.i + 1;
                    app.move=app.move-1;
                elseif(clickPoint(2)>205&&clickPoint(2)<305)
                    position = [5,205,position(3),position(4)];
                    app.a(app.i).Position = position;
                    app.i =app.i + 1;
                    app.move=app.move-1;
                elseif(clickPoint(2)>305&&clickPoint(2)<405)
                    position = [5,305,position(3),position(4)];
                    app.a(app.i).Position = position;
                    app.i =app.i + 1;
                    app.move=app.move-1;
                elseif(clickPoint(2)>405&&clickPoint(2)<505)
                    position = [5,405,position(3),position(4)];
                    app.a(app.i).Position = position;
                    app.i =app.i + 1;
                    app.move=app.move-1;
                end
            elseif (clickPoint(1)>105&&clickPoint(1)<205)
                if(clickPoint(2)>5&&clickPoint(2)<105)
                    position = [105,5,position(3),position(4)];
                    app.a(app.i).Position = position;
                    app.i =app.i + 1;
                    app.move=app.move-1;
                elseif(clickPoint(2)>105&&clickPoint(2)<205)
                    position = [105,105,position(3),position(4)];
                    app.a(app.i).Position = position;
                    app.i =app.i + 1;
                    app.move=app.move-1;
                elseif(clickPoint(2)>205&&clickPoint(2)<305)
                    position = [105,205,position(3),position(4)];
                    app.a(app.i).Position = position;
                    app.i =app.i + 1;
                    app.move=app.move-1;
                elseif(clickPoint(2)>305&&clickPoint(2)<405)
                    position = [105,305,position(3),position(4)];
                    app.a(app.i).Position = position;
                    app.i =app.i + 1;
                    app.move=app.move-1;
                elseif(clickPoint(2)>405&&clickPoint(2)<505)
                    position = [105,405,position(3),position(4)];
                    app.a(app.i).Position = position;
                    app.i =app.i + 1;
                    app.move=app.move-1;
                end
            elseif (clickPoint(1)>205&&clickPoint(1)<305)
                if(clickPoint(2)>5&&clickPoint(2)<105)
                    position = [205,5,position(3),position(4)];
                    app.a(app.i).Position = position;
                    app.i =app.i + 1;
                    app.move=app.move-1;
                elseif(clickPoint(2)>105&&clickPoint(2)<205)
                    position = [205,105,position(3),position(4)];
                    app.a(app.i).Position = position;
                    app.i =app.i + 1;
                    app.move=app.move-1;
                elseif(clickPoint(2)>205&&clickPoint(2)<305)
                    position = [205,205,position(3),position(4)];
                    app.a(app.i).Position = position;
                    app.i =app.i + 1;
                    app.move=app.move-1;
                elseif(clickPoint(2)>305&&clickPoint(2)<405)
                    position = [205,305,position(3),position(4)];
                    app.a(app.i).Position = position;
                    app.i =app.i + 1;
                    app.move=app.move-1;
                elseif(clickPoint(2)>405&&clickPoint(2)<505)
                    position = [205,405,position(3),position(4)];
                    app.a(app.i).Position = position;
                    app.i =app.i + 1;
                    app.move=app.move-1;
                end
            elseif (clickPoint(1)>305&&clickPoint(1)<405)
                if(clickPoint(2)>5&&clickPoint(2)<105)
                    position = [305,5,position(3),position(4)];
                    app.a(app.i).Position = position;
                    app.i =app.i + 1;
                    app.move=app.move-1;
                elseif(clickPoint(2)>105&&clickPoint(2)<205)
                    position = [305,105,position(3),position(4)];
                    app.a(app.i).Position = position;
                    app.i =app.i + 1;
                    app.move=app.move-1;
                elseif(clickPoint(2)>205&&clickPoint(2)<305)
                    position = [305,205,position(3),position(4)];
                    app.a(app.i).Position = position;
                    app.i =app.i + 1;
                    app.move=app.move-1;
                elseif(clickPoint(2)>305&&clickPoint(2)<405)
                    position = [305,305,position(3),position(4)];
                    app.a(app.i).Position = position;
                    app.i =app.i + 1;
                    app.move=app.move-1;
                elseif(clickPoint(2)>405&&clickPoint(2)<505)
                    position = [305,405,position(3),position(4)];
                    app.a(app.i).Position = position;
                    app.i =app.i + 1;
                    app.move=app.move-1;
                end
            end
            end
            if app.i == 9
                app.diyView.ok.Enable = 'on';
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @emptyClick, true);
            app.Caocao = uibutton(app.UIFigure, 'push');
            app.Caocao.Icon = '曹操.jpg';
            app.Caocao.Text = '';
            app.Button_1 = uibutton(app.UIFigure, 'push');
            app.Button_1.Text = '';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = creatView

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end